classdef Expt2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Ref1Button          matlab.ui.control.Button
        TestButton          matlab.ui.control.Button
        Ref2Button          matlab.ui.control.Button
        LocalisedButton     matlab.ui.control.Button
        NotlocalisedButton  matlab.ui.control.Button
        Ref1pathButton      matlab.ui.control.Button
        TestpathButton      matlab.ui.control.Button
        Ref2pathButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        ref1path
        testpath
        ref2path
        snr=100 % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Ref1Button
        function Ref1ButtonPushed(app, event)
            ref1 = app.ref1path
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' ref1 ' &'];
            unix(str2)
          %  unix(str)
        end

        % Button pushed function: Ref1pathButton
        function Ref1pathButtonPushed(app, event)
            [file,path] = uigetfile('*.wav');
            app.ref1path = fullfile(path,file);
         %   set(app.Ref1pathButton,'Enable','off');
        end

        % Button pushed function: TestpathButton
        function TestpathButtonPushed(app, event)
            [file,path] = uigetfile('*.wav');
            app.testpath = fullfile(path,file);
        %    set(app.TestpathButton,'Enable','off');
        end

        % Button pushed function: Ref2pathButton
        function Ref2pathButtonPushed(app, event)
            [file,path] = uigetfile('*.wav');
            app.ref2path = fullfile(path,file);
          %  set(app.Ref2pathButton,'Enable','off');
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
           if (app.snr == 100)
            
            app.snr
            test = app.testpath
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' test ' &'];
            unix(str2)
           
           else
            app.snr
            test = 'temp8.wav'
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py '  ' temp8.wav ' ' &'];
            unix(str2)
              
               
           end
           
        end

        % Button pushed function: Ref2Button
        function Ref2ButtonPushed(app, event)
            ref2 = app.ref2path
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' ref2 ' &'];
            unix(str2)
        end

        % Button pushed function: LocalisedButton
        function LocalisedButtonPushed(app, event)
            delta_snr= 20;
            disp("Reducing SNR");
            if(app.snr==100)
                app.snr=50;
            else
                app.snr = app.snr - delta_snr;
            end
            disp(app.snr);
            file_name = "temp7.wav";
            %unix_command = ["matlab -nodisplay -nojvm -r 'synthesis_vowel( "  file_name ',' app.snr '); exit;']
            %unix(unix_command)
       
            %status = system(['matlab' '-nodisplay' '-nojvm' '-r' 'synthesis_vowel' "filename.wav" app.snr  ';' 'exit' ';'])
            %status=system("matlab -nodisplay -nojvm -r 'synthesis_vowel "basic.wav" Arg2; exit;'")
            
            
            command= strcat("matlab -nodisplay -nojvm -r 'synthesis_vowel(", ' "temp8.wav" ',",",int2str(app.snr),");exit;'")
            system(command)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Ref1Button = uibutton(app.UIFigure, 'push');
            app.Ref1Button.ButtonPushedFcn = createCallbackFcn(app, @Ref1ButtonPushed, true);
            app.Ref1Button.Text = {'Ref1'; ''};
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.Ref2Button = uibutton(app.UIFigure, 'push');
            app.Ref2Button.ButtonPushedFcn = createCallbackFcn(app, @Ref2ButtonPushed, true);
            app.Ref2Button.Text = {'Ref2'; ''};
            app.LocalisedButton = uibutton(app.UIFigure, 'push');
            app.LocalisedButton.ButtonPushedFcn = createCallbackFcn(app, @LocalisedButtonPushed, true);
            app.LocalisedButton.Text = 'Localised';
            app.NotlocalisedButton = uibutton(app.UIFigure, 'push');
            app.NotlocalisedButton.Text = {'Not localised'; ''};
            app.Ref1pathButton = uibutton(app.UIFigure, 'push');
            app.Ref1pathButton.ButtonPushedFcn = createCallbackFcn(app, @Ref1pathButtonPushed, true);
            app.Ref1pathButton.Text = 'Ref1 path';
            app.TestpathButton = uibutton(app.UIFigure, 'push');
            app.TestpathButton.ButtonPushedFcn = createCallbackFcn(app, @TestpathButtonPushed, true);
            app.TestpathButton.Text = 'Test path';
            app.Ref2pathButton = uibutton(app.UIFigure, 'push');
            app.Ref2pathButton.ButtonPushedFcn = createCallbackFcn(app, @Ref2pathButtonPushed, true);
            app.Ref2pathButton.Text = 'Ref2 path';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Expt2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end